classdef Printer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        PrintTab                 matlab.ui.container.Tab
        ASCIIEditFieldLabel      matlab.ui.control.Label
        PrintBrowseButton        matlab.ui.control.Button
        PrintStartButton         matlab.ui.control.Button
        PrintStopButton          matlab.ui.control.Button
        PrintEStopButton         matlab.ui.control.Button
        PrintFilepathField       matlab.ui.control.EditField
        PrintOTFCheckBox         matlab.ui.control.CheckBox
        experimentalLabel        matlab.ui.control.Label
        RealtimeTab              matlab.ui.container.Tab
        XEditFieldLabel          matlab.ui.control.Label
        RtXPosField              matlab.ui.control.NumericEditField
        YEditFieldLabel          matlab.ui.control.Label
        RtYPosField              matlab.ui.control.NumericEditField
        ZEditFieldLabel          matlab.ui.control.Label
        RtZPosField              matlab.ui.control.NumericEditField
        RtPositionLabel          matlab.ui.control.Label
        RtGoButton               matlab.ui.control.Button
        VelocityEditFieldLabel   matlab.ui.control.Label
        RtVelocityField          matlab.ui.control.NumericEditField
        RtCalcCelCheckBox        matlab.ui.control.CheckBox
        RtAddButton              matlab.ui.control.Button
        RtWaitStopCheckBox       matlab.ui.control.CheckBox
        RtHomeButton             matlab.ui.control.Button
        RtESTOPButton            matlab.ui.control.Button
        ConvertTab               matlab.ui.container.Tab
        GCodeEditFieldLabel      matlab.ui.control.Label
        ConvInputFilepathField   matlab.ui.control.EditField
        ConvInputBrowseButton    matlab.ui.control.Button
        OutputEditFieldLabel     matlab.ui.control.Label
        ConvOutputFilepathField  matlab.ui.control.EditField
        ConvOutputBrowseButton   matlab.ui.control.Button
        ConvStartButton          matlab.ui.control.Button
        ConvStartPrintCheckBox   matlab.ui.control.CheckBox
        ConvStatsCheckBox        matlab.ui.control.CheckBox
        ConvSLabel_2             matlab.ui.control.Label
        DebugTab                 matlab.ui.container.Tab
        DebugSendButton          matlab.ui.control.Button
        DebugConnectButton       matlab.ui.control.Button
        DebugDisconnectButton    matlab.ui.control.Button
        DebugResetButton         matlab.ui.control.Button
        CommandEditFieldLabel_2  matlab.ui.control.Label
        DebugCommandField        matlab.ui.control.EditField
        ResponseTextAreaLabel_2  matlab.ui.control.Label
        DebugResponseText        matlab.ui.control.TextArea
        DebugStringCheckBox      matlab.ui.control.CheckBox
        stringLabel_2            matlab.ui.control.Label
        DebugQueryButton         matlab.ui.control.Button
    end


    methods (Access = private)
    
        function GCodeParse(app, input, output)
            
            printStats = app.ConvStatsCheckBox.Value;
            
    	    if exist(output, 'file') == 2
                eval(sprintf('delete %s', output)); % There must be a better way...
    	    end
        
    	    global CURRENT_POS;
    	    global CURRENT_SPEED;
    	    CURRENT_POS = [0,0,0];
    	    CURRENT_SPEED = [50,50,50];
        
            fid = fopen(input);
            frewind(fid);
            
            if (printStats)
                unknownCommands = []; % stores indices in uniqueCommands
                uniqueCommands = {}; % stores actual commands
                nUni = 0;
            end
            
            lineNum = 0;
            lineEnd = numel(cell2mat(textscan(fid,'%1c%*[^\n]')));
            frewind(fid);
            totalOutputLength = 0;

            nCommands = 0;
            
            h = waitbar(0,'Converting...','Name',input,...
                'CreateCancelBtn',...
                'setappdata(gcbf,''canceling'',1)');
            setappdata(h,'canceling',0)
            set(h, 'WindowStyle', 'modal', 'CloseRequestFcn', @WaitBarClose);
            
                function WaitBarClose(hObject, ~)
                    setappdata(hObject,'canceling', 1)
                end
             
            while true
                
                if getappdata(h,'canceling')
                    delete(h);
                    app.ConvStartButton.Enable = 'on';
                    return;
                end
                
                g = fgetl(fid);
                
                lineNum = lineNum + 1;    
                
                % Update progress bar
                waitbar(lineNum/lineEnd);
                
                % Read g-code line
                if (g == -1) % End of file. Stop reading.
                    break;
                elseif isempty(g) % Line is blank. Skip to next.
                    continue;
                elseif (g(1) == ';') % Line is comment. Skip to next.
                    continue;
                end % Line is command. Parse.
                
                spaces = strfind(g, ' ');
                if isempty(spaces)
                    gCommand = g; % Command has no arguments
                else
                    gCommand = g(1:spaces(1)-1); % Extract first characters up to space
                end
            
                if (printStats)
                    unique = true;
                    for i = 1:nUni
            	        if (strcmp(uniqueCommands{i,1}, gCommand))
    		            unique = false;
    		            uniqueCommands{i,2} = uniqueCommands{i,2} + 1;
            	        end
                    end
                
                    if unique
            	        nUni = nUni + 1;
            	        uniqueCommands{nUni, 1} = gCommand;
            	        uniqueCommands{nUni, 2} = 1;
                    end
                end
                
                
                % Generate command
                if (strcmp(gCommand, 'G0') || strcmp(gCommand, 'G1'))
                    asciiCommand = G1(g(spaces(1)+1:length(g)));
                    dlmwrite(output, asciiCommand, 'delimiter', '', '-append');
                    nCommands = nCommands + 1;
                    if (printStats)
                    	totalOutputLength = totalOutputLength + length(asciiCommand);
                    end
                else
                    if (printStats && unique)
                    	unknownCommands = [unknownCommands, nUni];
                    end
                end
            end
            
            dlmwrite(output, num2str(nCommands), 'delimiter', '', '-append');
            
            delete(h);
            
            if (printStats)
                stats = cell(nUni, 1);
                for k=1:nUni
                    if (any(k == unknownCommands))
                        statLine = '!'; % prepend stats line with exclamation mark if unknown
                    else
                        statLine = ' ';
                    end
                    stats{k, 1} = sprintf("%s  %s     %d", statLine, uniqueCommands{k,:});
                end
            
                statsTail = {'';sprintf('Total output length: %d', totalOutputLength)};
                
                uiwait(msgbox([stats;statsTail], 'Stats'));
            end
            
        end

        function  success = Connect(app)
            global ESP;
            success = true;
            
            Disconnect(app); % Does nothing if there's already no connection
            
            comPort = 3; % No reason to change this on Windows. You'd need another way to do it on Mac.
            
            % For Python use:
            % ser = serial.Serial('/dev/ttyUSB0', 19200, timeout=.5)
            % Baud is different because the connection would have to go through the actual serial port on the ESP.
            ESP = serial(sprintf('COM%0.0f', comPort));
            set(ESP, 'baudrate', 921600);
            set(ESP, 'terminator', 13); % \n
            set(ESP, 'Timeout', 1); % It replies rather quickly.
            
            try
                fopen(ESP);
            catch
                success = false;
                return;
            end
            
            fprintf(ESP, '1AU1000;2AU1000;3AU1000;'); % Set max accel/decel
            fprintf(ESP, '1AC1000;2AC1000;3AC1000;'); % Set accel
            fprintf(ESP, '1AG1000;2AG1000;3AG1000;'); % Set decel
            fprintf(ESP, '1AE1000;2AE1000;3AE1000;'); % Set e-stop decel
            fprintf(ESP, sprintf('1VA%0.0f;2VA%0.0f;3VA%0.0f;', [50, 50, 50])); % Set velocity
            fprintf(ESP, '1MO;2MO;3MO;'); % Turn on motors
        end
        
        function Disconnect(app, reset)
            global ESP;
            
            if (nargin < 2)
                reset = false;
            end
            
            if (reset)
                try
                    ESP.Timeout = 1;
                    fprintf(ESP, 'RS;'); % Reset
                end % Don't care if fails.
            end
            
            ESP = 1; % Directs all further ESP-related output to the console.
            
            allInstruments = instrfind;
            
            try
                fclose(allInstruments);
            end
            % Don't care if these fail.
            try
                delete(allInstruments);
            end
        end
        
        function Send(app, isQuery)
            global ESP;
            if isnumeric(ESP)
                app.DebugResponseText.Value = '[Disconnected]';
                return;
            end
            
            command = app.DebugCommandField.Value;
            responseIsString =  app.DebugStringCheckBox.Value;
            
            if (isQuery)
                app.DebugResponseText.Value = '';
                response = Query(app, command, responseIsString);
                if isempty(response)
                   response = '[Timeout]';
                   responseIsString = true;
                end
                if (~responseIsString)
                    response = num2cell(response)'; 
                    response = strtrim(sprintf('%f ' , response{:}));
                end
                app.DebugResponseText.Value = response;
            else
                fprintf(ESP, command);
            end
        end
        
        function response = Query(app, message, isString)
            global ESP
            if isnumeric(ESP)
                return;
            end
            
            if (nargin < 3)
                isString = false;
            end
            
            if (isString)
                response = '';
            else
                response = [];
            end
            
            % There shouldn't be anything in BytesAvailable right now, so clear it.
            flushinput(ESP);
            
            fprintf(ESP, message);
            
            firstRead = true; % Or else it'll skip past the whole while loop
            
            warning('off','MATLAB:serial:fscanf:unsuccessfulRead');
            
            while (firstRead || ESP.BytesAvailable > 1)
                responseRaw = fscanf(ESP);
                
                if (~isempty(responseRaw))
                    if (isString)
                        response{length(response)+1} = responseRaw(1:length(responseRaw)-1);
                        % Trims off odd ends.
                    else
                    response = [response; str2num(responseRaw)];
                    % Output isn't always a double, so use str2num
                    end
                end
                firstRead = false;
                
            end
            warning('on','MATLAB:serial:fscanf:unsuccessfulRead');
            
            % response already set.
        end
        
        function GCodeStream(app, input)
            global ESP;
    	    global CURRENT_POS;
    	    global CURRENT_SPEED;
    	    CURRENT_POS = [0,0,0];
    	    CURRENT_SPEED = [50,50,50];
        
            fid = fopen(input);
            frewind(fid);
            
            lineNum = 0;
            lineEnd = numel(cell2mat(textscan(fid,'%1c%*[^\n]')));
            frewind(fid);
            
            h = waitbar(0,'Printing...','Name',input,...
                'CreateCancelBtn',...
                'setappdata(gcbf,''canceling'',1)');
            setappdata(h,'canceling',0)
            set(h, 'WindowStyle', 'modal', 'CloseRequestFcn', @WaitBarClose);
            
                function WaitBarClose(hObject, ~)
                    setappdata(hObject,'canceling', 1)
                end
            
            
            while true
                
                if getappdata(h,'canceling')
                    delete(h);
                    app.ConvStartButton.Enable = 'on';
                    return;
                end
                
                g = fgetl(fid);
                
                lineNum = lineNum + 1;    
                
                % Update progress bar
                waitbar(lineNum/lineEnd);
                
                % Read g-code line
                if (g == -1) % End of file. Stop reading.
                    break;
                elseif isempty(g) % Line is blank. Skip to next.
                    continue;
                elseif (g(1) == ';') % Line is comment. Skip to next.
                    continue;
                end % Line is command. Parse.
                
                spaces = strfind(g, ' ');
                if isempty(spaces)
                    % Command has no arguments. Ignore (for our purposes)
                    continue;
                else
                    gCommand = g(1:spaces(1)-1); % Extract first characters up to space
                end
                
                % Generate command
                if (strcmp(gCommand, 'G0') || strcmp(gCommand, 'G1'))
                    asciiCommand = G1(g(spaces(1)+1:length(g)));
                    fscanf(ESP);
                    fprintf(ESP, asciiCommand);
                end
            end
            
            delete(h);
            uiwait(msgbox('Print completed!', 'Done'));
        end
        
    end % methods


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Center window
            SS = get(0,'ScreenSize');
            app.UIFigure.Position(1:2) = (SS(3:4) - app.UIFigure.Position(3:4)) ./ 2;
            
            % Store all tabs for switching later
            global TABS
            TABS = get(app.TabGroup, 'Children');
            
            % Usage to go to third tab: app.TabGroup.SelectedTab = TABS(3);
            
            global ESP;
            ESP = 1;
            
        end

        % Button pushed function: ConvInputBrowseButton
        function ConvInputBrowseButtonPushed(app, event)
            [filename, pathname] = uigetfile({...
        	'*.gcode','G-Code File';...
        	'*.*','All Files' },...
        	'Select a G-Code file',...
                app.ConvInputFilepathField.Value);
            
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';% Prevents loss of focus bug after file picker closes.
            
            if ~ischar(filename) % If they cancel
        	return;
            end
            
            app.ConvInputFilepathField.Value = strcat(pathname, filename);
        end

        % Button pushed function: ConvOutputBrowseButton
        function ConvOutputBrowseButtonPushed(app, event)
            if isempty(app.ConvOutputFilepathField.Value)
                if isempty(app.ConvInputFilepathField.Value)
                    defaultPath = '';
                else
                    [inPath, inName, ~] = fileparts(app.ConvInputFilepathField.Value);
                    defaultPath = strcat(inPath, '\', inName, '.ascii');
                end
            else
                defaultPath = app.ConvOutputFilepathField.Value;
            end
            
            
            [filename, pathname] = uiputfile({...
                '*.ascii','Print File';...
                '*.*','All Files' },...
        	'Choose output location',...
                defaultPath);
            
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';% Prevents loss of focus bug after file picker closes.
            
            
            if ~ischar(filename) % If they cancel
        	return;
            end
            
            app.ConvOutputFilepathField.Value = strcat(pathname, filename);
        end

        % Button pushed function: ConvStartButton
        function ConvStartButtonPushed(app, event)
            global canSwitchTab;
            global TABS;
            input = app.ConvInputFilepathField.Value;
            output = app.ConvOutputFilepathField.Value;
            
            if isempty(input) || isempty(output)
                uiwait(errordlg('Must select an input and output location!','File Error'));
                return;
            end
            
            if ~exist(input, 'file')
                uiwait(errordlg('Input file does not exist!','File Error'));
                return;
            end
            canSwitchTab = false;
            app.ConvStartButton.Enable = 'off';
            GCodeParse(app, input, output);
            app.ConvStartButton.Enable = 'on';
            canSwitchTab = true;
            if strcmp(app.ConvStartPrintCheckBox.Enable, 'on')
                app.PrintFilepathField.Value = app.ConvOutputFilepathField.Value;
                app.TabGroup.SelectedTab = TABS(1);
            end
        end

        % Button pushed function: DebugConnectButton
        function DebugConnectButtonPushed(app, event)
            if Connect(app);
                app.DebugResponseText.Value = '[Connected]';
            else
                app.DebugResponseText.Value = '[Error]';
            end
        end

        % Button pushed function: DebugDisconnectButton
        function DebugDisconnectButtonPushed(app, event)
            Disconnect(app);
            app.DebugResponseText.Value = '[Disconnected]';
        end

        % Button pushed function: DebugQueryButton
        function DebugQueryButtonPushed(app, event)
            Send(app, true);
        end

        % Button pushed function: DebugResetButton
        function DebugResetButtonPushed(app, event)
            Disconnect(app, true);
            app.DebugResponseText.Value = '[Reset] [Disconnected]';
        end

        % Button pushed function: DebugSendButton
        function DebugSendButtonPushed(app, event)
            Send(app, false);
        end

        % Button pushed function: PrintBrowseButton
        function PrintBrowseButtonPushed(app, event)
            if ~app.PrintOTFCheckBox.Value
                [filename, pathname] = uigetfile({...
            	'*.ascii','Print File';...
                '*.*','All Files' },...
            	'Choose print file',...
                app.PrintFilepathField.Value);
            else
                [filename, pathname] = uigetfile({...
                '*.gcode','G-Code File';...
                '*.*','All Files' },...
            	'Choose G-Code file',...
                app.PrintFilepathField.Value);
            end
            
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';% Prevents loss of focus bug after file picker closes.
            
            if ~ischar(filename) % If they cancel
        	return;
            end
            
            app.PrintFilepathField.Value = strcat(pathname, filename);
        end

        % Button pushed function: PrintEStopButton, RtESTOPButton
        function PrintEStopButtonPushed(app, event)
            global ESP
            
            if isnumeric(ESP)
                return;
            end
            
            fprintf(ESP, 'ST;AB'); % Stop motion, trigger e-stop, turn off all motors.
        end

        % Value changed function: PrintOTFCheckBox
        function PrintOTFCheckBoxValueChanged(app, event)
            app.PrintFilepathField.Value = ''; % Clear the textbox because the accepted file extensions change.
        end

        % Button pushed function: PrintStartButton
        function PrintStartButtonPushed(app, event)
            global ESP
            
            if app.PrintOTFCheckBox.Value
                if ~Connect(app)
                    uiwait(warndlg('Cannot detect printer!', 'No Connection', 'modal'));
                    return;
                end
                oldTO = ESP.Timeout;
                ESP.Timeout = 20; % Query is set velocities, set target positions, wait stop, ask position.
                Query(app, '1VA20;2VA20;3VA20;1PA-160;2PA-160;3PA160;1WS;2WS;3WS;1TP?');
                ESP.Timeout = oldTO;
                pause(1); % Let it settle
                fprintf(ESP, '1DH;2DH;3DH'); % Set as origin
                GCodeStream(app, app.PrintFilepathField.Value);
            else
                uiwait(warndlg('That part of this program isn''t done yet! Try on-the-fly mode.', 'Not implemented', 'modal'));
            end
        end

        % Button pushed function: PrintStopButton
        function PrintStopButtonPushed(app, event)
           uiwait(warndlg('That part of this program isn''t done yet!', 'Not implemented', 'modal'));
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            global canSwitchTab;
            
            if ~canSwitchTab
                app.TabGroup.SelectedTab = event.OldValue;
            end
            
        end

        % Button pushed function: RtGoButton
        function RtGoButtonPushed(app, event)
            uiwait(warndlg('That part of this program isn''t done yet!', 'Not implemented', 'modal'));
            
            global ESP;
            
            pos = [app.RtXPosField.Value app.RtYPosField.Value app.RtZPosField.Value];
            vel = [app.RtXVelField.Value app.RtYVelField.Value app.RtZVelField.Value];
            acc = [app.RtXAccField.Value app.RtYAccField.Value app.RtZAccField.Value];
            
            fprintf(ESP, '1AU1000;2AU1000;3AU1000;'); % Set max accel/decel
            fprintf(ESP, '1AC1000;2AC1000;3AC1000;'); % Set accel
            fprintf(ESP, '1AG1000;2AG1000;3AG1000;'); % Set decel
            fprintf(ESP, '1AE1000;2AE1000;3AE1000;'); % Set e-stop decel
            fprintf(ESP, sprintf('1VA%0.0f;2VA%0.0f;3VA%0.0f;', [50, 50, 50])); % Set velocity
            fprintf(ESP, '1MO;2MO;3MO;'); % Turn on motors
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'ESP Printer Manager';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.PrintTab = uitab(app.TabGroup);
            app.PrintTab.Title = 'Print';
            app.ASCIIEditFieldLabel = uilabel(app.PrintTab);
            app.ASCIIEditFieldLabel.Text = 'Print File';
            app.PrintBrowseButton = uibutton(app.PrintTab, 'push');
            app.PrintBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @PrintBrowseButtonPushed, true);
            app.PrintBrowseButton.Text = 'Browse';
            app.PrintStartButton = uibutton(app.PrintTab, 'push');
            app.PrintStartButton.ButtonPushedFcn = createCallbackFcn(app, @PrintStartButtonPushed, true);
            app.PrintStartButton.Text = 'Start Print';
            app.PrintStopButton = uibutton(app.PrintTab, 'push');
            app.PrintStopButton.ButtonPushedFcn = createCallbackFcn(app, @PrintStopButtonPushed, true);
            app.PrintStopButton.Enable = 'off';
            app.PrintStopButton.Text = 'Stop Print';
            app.PrintEStopButton = uibutton(app.PrintTab, 'push');
            app.PrintEStopButton.ButtonPushedFcn = createCallbackFcn(app, @PrintEStopButtonPushed, true);
            app.PrintEStopButton.Text = 'E-STOP';
            app.PrintFilepathField = uieditfield(app.PrintTab, 'text');
            app.PrintOTFCheckBox = uicheckbox(app.PrintTab);
            app.PrintOTFCheckBox.ValueChangedFcn = createCallbackFcn(app, @PrintOTFCheckBoxValueChanged, true);
            app.PrintOTFCheckBox.Text = 'On-the-fly mode';
            app.PrintOTFCheckBox.Value = true;
            app.experimentalLabel = uilabel(app.PrintTab);
            app.experimentalLabel.Text = '(experimental)';
            app.RealtimeTab = uitab(app.TabGroup);
            app.RealtimeTab.Title = 'Realtime';
            app.XEditFieldLabel = uilabel(app.RealtimeTab);
            app.XEditFieldLabel.Text = 'X';
            app.RtXPosField = uieditfield(app.RealtimeTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.RealtimeTab);
            app.YEditFieldLabel.Text = 'Y';
            app.RtYPosField = uieditfield(app.RealtimeTab, 'numeric');
            app.ZEditFieldLabel = uilabel(app.RealtimeTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.RtZPosField = uieditfield(app.RealtimeTab, 'numeric');
            app.RtPositionLabel = uilabel(app.RealtimeTab);
            app.RtPositionLabel.Text = 'Position (mm)';
            app.RtGoButton = uibutton(app.RealtimeTab, 'push');
            app.RtGoButton.ButtonPushedFcn = createCallbackFcn(app, @RtGoButtonPushed, true);
            app.RtGoButton.Text = 'Go';
            app.VelocityEditFieldLabel = uilabel(app.RealtimeTab);
            app.VelocityEditFieldLabel.Text = 'Velocity';
            app.RtVelocityField = uieditfield(app.RealtimeTab, 'numeric');
            app.RtCalcCelCheckBox = uicheckbox(app.RealtimeTab);
            app.RtCalcCelCheckBox.Text = 'Calculate axes individually';
            app.RtAddButton = uibutton(app.RealtimeTab, 'push');
            app.RtAddButton.Text = 'Add';
            app.RtWaitStopCheckBox = uicheckbox(app.RealtimeTab);
            app.RtWaitStopCheckBox.Text = 'Wait for motion to stop';
            app.RtHomeButton = uibutton(app.RealtimeTab, 'push');
            app.RtHomeButton.Text = 'Home';
            app.RtESTOPButton = uibutton(app.RealtimeTab, 'push');
            app.RtESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @PrintEStopButtonPushed, true);
            app.RtESTOPButton.Text = 'E-STOP';
            app.ConvertTab = uitab(app.TabGroup);
            app.ConvertTab.Title = 'Convert';
            app.GCodeEditFieldLabel = uilabel(app.ConvertTab);
            app.GCodeEditFieldLabel.Text = 'G-Code';
            app.ConvInputFilepathField = uieditfield(app.ConvertTab, 'text');
            app.ConvInputBrowseButton = uibutton(app.ConvertTab, 'push');
            app.ConvInputBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @ConvInputBrowseButtonPushed, true);
            app.ConvInputBrowseButton.Text = 'Browse';
            app.OutputEditFieldLabel = uilabel(app.ConvertTab);
            app.OutputEditFieldLabel.Text = 'Output';
            app.ConvOutputFilepathField = uieditfield(app.ConvertTab, 'text');
            app.ConvOutputBrowseButton = uibutton(app.ConvertTab, 'push');
            app.ConvOutputBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @ConvOutputBrowseButtonPushed, true);
            app.ConvOutputBrowseButton.Text = 'Browse';
            app.ConvStartButton = uibutton(app.ConvertTab, 'push');
            app.ConvStartButton.ButtonPushedFcn = createCallbackFcn(app, @ConvStartButtonPushed, true);
            app.ConvStartButton.Text = 'Start Conversion';
            app.ConvStartPrintCheckBox = uicheckbox(app.ConvertTab);
            app.ConvStartPrintCheckBox.Text = 'Start print immediately on completion?';
            app.ConvStatsCheckBox = uicheckbox(app.ConvertTab);
            app.ConvStatsCheckBox.Text = 'View statistics on completion?';
            app.ConvSLabel_2 = uilabel(app.ConvertTab);
            app.ConvSLabel_2.Text = '(Slows down conversion slightly)';
            app.DebugTab = uitab(app.TabGroup);
            app.DebugTab.Title = 'Debug';
            app.DebugSendButton = uibutton(app.DebugTab, 'push');
            app.DebugSendButton.ButtonPushedFcn = createCallbackFcn(app, @DebugSendButtonPushed, true);
            app.DebugSendButton.Text = 'Send';
            app.DebugConnectButton = uibutton(app.DebugTab, 'push');
            app.DebugConnectButton.ButtonPushedFcn = createCallbackFcn(app, @DebugConnectButtonPushed, true);
            app.DebugConnectButton.Text = 'Connect';
            app.DebugDisconnectButton = uibutton(app.DebugTab, 'push');
            app.DebugDisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DebugDisconnectButtonPushed, true);
            app.DebugDisconnectButton.Text = 'Disconnect';
            app.DebugResetButton = uibutton(app.DebugTab, 'push');
            app.DebugResetButton.ButtonPushedFcn = createCallbackFcn(app, @DebugResetButtonPushed, true);
            app.DebugResetButton.Text = 'Reset';
            app.CommandEditFieldLabel_2 = uilabel(app.DebugTab);
            app.CommandEditFieldLabel_2.Text = 'Command';
            app.DebugCommandField = uieditfield(app.DebugTab, 'text');
            app.ResponseTextAreaLabel_2 = uilabel(app.DebugTab);
            app.ResponseTextAreaLabel_2.Text = 'Response';
            app.DebugResponseText = uitextarea(app.DebugTab);
            app.DebugResponseText.Editable = 'off';
            app.DebugStringCheckBox = uicheckbox(app.DebugTab);
            app.DebugStringCheckBox.Text = 'Expect';
            app.stringLabel_2 = uilabel(app.DebugTab);
            app.stringLabel_2.Text = 'string';
            app.DebugQueryButton = uibutton(app.DebugTab, 'push');
            app.DebugQueryButton.ButtonPushedFcn = createCallbackFcn(app, @DebugQueryButtonPushed, true);
            app.DebugQueryButton.Text = 'Query';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Printer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end